classdef ResultWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResultWindowFigure  matlab.ui.Figure
        VisualizeButton     matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        ReturnButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        winnumber % Description
        CallingApp

        L, W,TarNum, APNum, RandomTimes, APCoordinate, imgpath, h

        TarCoordinate, TarCoordinate_mat, Vector, vecX = [1,0], Theta, Tau, steervec, Phi, Omega

        SNR = 10, samples = 100, 

        Tar_XX, Tar_YY, Tar_ZZ, AP_XX, AP_YY, AP_ZZ
        APcoor_iraw, APcoor_iraw_XX, APcoor_iraw_YY, APcoor_iraw_ZZ
        Tarcoor_iraw, Tarcoor_iraw_XX, Tarcoor_iraw_YY, Tarcoor_iraw_ZZ
        AP_Tar_mat_XX, AP_Tar_mat_YY, AP_Tar_mat_ZZ

        %% 信号参数
        LIGHTSPEED = 3e8;                   % 光速 3*10^8 [m/s]
        fc = 5.8e9;                         % 5.8GHz
        lambda = 3e8 / 5.8e9                %LIGHTSPEED/fc, 约3cm
        Nc = 30;                            % number of subcarriers 至少为2
        BW = 20e6;                          % Bandwidth = 20MHz
        fcmin =  5.8e9 - 20e6               %fc - BW
        Delta_f = 20e6 / 30                 %BW/Nc, 子载波频率间隔
        Nrx = 3                             %天线数量
        ant_dist = 3e8 / 5.8e9 / 2          %lambda/2
   
        cellArrayText % Description
        SteervecPath, CoordinatePath
    end
    
    methods (Access = private)
        function outPut(app,str)
            string = sprintf('%s\n', str); % 将数据格式化为字符串或字符向量
            app.cellArrayText=horzcat(app.cellArrayText,string); % 水平串联数组
            app.TextArea.Value = app.cellArrayText; % 给TextArea赋值
        end

        function output_mat(app, mat)
            string = mat2str(mat, 2);
            app.cellArrayText=horzcat(app.cellArrayText,string); % 水平串联数组
            app.TextArea.Value = app.cellArrayText; % 给TextArea赋值
        end

        function savefigure2img(~, imgname)
            frame = getframe(gcf);
            im = frame2im(frame);
            imwrite(im,imgname);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, UpperApp, Appnum, L, W, TarNum, RandomTimes, APCoor, APNum)
            app.CallingApp = UpperApp;
            app.winnumber = Appnum; 
            app.L = L;
            app.W = W;
            app.TarNum = TarNum;
            app.RandomTimes = RandomTimes;
            app.APCoordinate = APCoor;
            app.APNum = APNum;
            
            app.cellArrayText{1} = sprintf('%s\n', 'AP点坐标为：'); % 赋初值
            output_mat(app, app.APCoordinate);
            app.TextArea.Value=app.cellArrayText{1}; % 文本区域中的初始显示信息 

            if app.winnumber == 0
                delete Data/AOA/Steervec_data/*.txt
                delete Data/AOA/CSI_data/*.txt
                delete Data/AOA/Coordination/*.txt
                delete Data/AOA/Fading_data/*.txt
                delete Data/AOA/Noise_data/*.txt

                for j = 1:app.RandomTimes
                    outPut(app, ['第' num2str(j) '次随机取定位目标点：']);
                    app.TarCoordinate(1,:) = rand(app.TarNum,2).*(ones(app.TarNum,1)*[app.L app.W]);
                    app.TarCoordinate_mat(j,:) = app.TarCoordinate; %保存所有的目标定位点坐标，为可视化标点使用
                    output_mat(app, app.TarCoordinate);
                    app.Vector(:,:) = app.APCoordinate - app.TarCoordinate;
                    app.Theta = atan(app.Vector(:,1) ./ app.Vector(:,2));
                    outPut(app, "AOA角度(弧度)：")     
                    output_mat(app, app.Theta)
                    app.steervec = zeros(app.Nrx*app.Nc, app.APNum);
                    for ii = 1:app.Nrx
	                    for jj = 1:app.Nc
                            app.steervec((ii-1)*app.Nc + jj,:) = exp(-1j*2*pi*ii*app.ant_dist*sin(app.Theta) * (app.fcmin + jj*app.Delta_f) / app.LIGHTSPEED);
	                    end
                    end
                    Gamma = randn(app.APNum,app.samples)+randn(app.APNum,app.samples)*1j;
                    X = app.steervec * Gamma;
                    CSI = awgn(X,app.SNR,'measured'); 
                    Steernoise = awgn(app.steervec,app.SNR,'measured');
                    CSI_path = ['Data/AOA/CSI_data/CSI_', num2str(j), '.txt'];
                    noise_path = ['Data/AOA/Noise_data/noise_', num2str(j), '.txt'];
                    Fading_path = ['Data/AOA/Fading_data/Fading_', num2str(j), '.txt'];
                    app.SteervecPath = ['Data/AOA/Steervec_data/steervec_', num2str(j), '.txt'];
                    app.CoordinatePath = ['Data/AOA/Coordination/Coordinate_', num2str(j), '.txt'];
                    
                    writematrix(X, Fading_path)
                    writematrix(Steernoise, noise_path)
                    writematrix(CSI, CSI_path);
                    writematrix(app.steervec, app.SteervecPath);
                    writematrix(app.TarCoordinate, app.CoordinatePath)
                    outPut(app, '保存成功')
                end

            elseif app.winnumber == 1
                delete Data/AOATOF/Data/*.txt
                delete Data/AOATOF/CSI_data/*.txt
                delete Data/AOATOF/Coordination/*.txt
                for j = 1:app.RandomTimes
                    outPut(app, ['第' num2str(j) '次随机取定位目标点：']);
                    app.TarCoordinate(1,:) = rand(app.TarNum,2).*(ones(app.TarNum,1)*[app.L app.W]);
                    app.TarCoordinate_mat(j,:) = app.TarCoordinate; %保存所有的目标定位点坐标，为可视化标点使用
                    output_mat(app, app.TarCoordinate);
                    app.Vector(:,:) = app.APCoordinate - app.TarCoordinate;
                    app.Theta = acos(dot(app.Vector,app.vecX)/(norm(app.Vector)*norm(app.vecX)));
                    app.Tau = sum(abs(app.Vector).^2,2).^(1/2) / app.LIGHTSPEED;
                    outPut(app, "TOF时间（ns）：")
                    output_mat(app, app.Tau)
                    outPut(app, "AOA角度（弧度）：")
                    output_mat(app, app.Theta)

                    app.steervec = zeros(app.Nrx*app.Nc, app.APNum);
	                app.Phi = zeros(app.Nrx,app.APNum);
	                app.Omega = zeros(app.Nc,app.APNum);
	                for ii = 1:app.Nrx
	                    app.Phi(ii, :) = exp(-1j*2*pi*ii*app.ant_dist*sin(app.Theta*pi/180)/app.lambda);
	                    for jj = 1:app.Nc
	                        app.Omega(jj,:) = exp(-1j*2*pi*jj*app.Delta_f*app.Tau);
	                        app.steervec((ii-1)*app.Nc + jj,:) = app.Phi(ii)*app.Omega(jj);
	                    end
	                end
                    app.SteervecPath = ['AOATOF/Data/steervec_', num2str(j), '.txt'];
                    app.CoordinatePath = ['AOATOF/Data/Coordinate_', num2str(j), '.txt'];
                    writematrix(app.steervec, app.SteervecPath);
                    writematrix(app.TarCoordinate, app.CoordinatePath)
                    outPut(app, '保存成功')
                end
            end
        end

        % Button pushed function: ReturnButton
        function ReturnButtonPushed(app, event)
            if app.winnumber == 1
               set(app.ResultWindowFigure,'Visible','off'); 
               run AOATOF_Data_Input.mlapp
            else
               set(app.ResultWindowFigure,'Visible','off'); 
               run AOA_Data_Input.mlapp
            end
        end

        % Button pushed function: VisualizeButton
        function VisualizeButtonPushed(app, event)
            close all
            delete Data/AOA/Img/*.jpg
            delete Data/AOATOF/Img/*.jpg
            app.Tar_XX = app.TarCoordinate_mat(:,1)';
            app.Tar_YY = app.TarCoordinate_mat(:,2)';

            app.AP_XX = app.APCoordinate(:,1);
            app.AP_YY = app.APCoordinate(:,2);

            for ii = 1:app.APNum
                app.APcoor_iraw = app.APCoordinate(ii,:);
                app.APcoor_iraw_XX = app.APcoor_iraw(:,1);
                app.APcoor_iraw_YY = app.APcoor_iraw(:,2);
                for jj = 1:app.RandomTimes
                    app.h = figure(jj);
                    set(app.h, 'Visible', 'off');
                    app.Tarcoor_iraw = app.TarCoordinate_mat(jj,:);
                    app.Tarcoor_iraw_XX = app.Tarcoor_iraw(:,1);
                    app.Tarcoor_iraw_YY = app.Tarcoor_iraw(:,2);

                    app.AP_Tar_mat_XX = [app.APcoor_iraw_XX; app.Tarcoor_iraw_XX];
                    app.AP_Tar_mat_YY = [app.APcoor_iraw_YY; app.Tarcoor_iraw_YY];

                    plot(app.AP_Tar_mat_XX, app.AP_Tar_mat_YY, '--')
                    hold on

                    scatter(app.Tar_XX, app.Tar_YY,'.')
                    hold on
                    scatter(app.AP_XX, app.AP_YY,'p')
                    hold on
                    xlabel('X')
                    ylabel('Y')
                    grid on
                    xlim([0 app.L]) % X,Y,Z轴取值范围
                    ylim([0 app.W])
                    hold on
                    if app.winnumber == 0
                        app.imgpath = ['Data/AOA/Img/Img_', num2str(jj), '.jpg'];
                    elseif app.winnumber == 1
                        app.imgpath = ['Data/AOATOF/Img/Img_', num2str(jj), '.jpg'];
                    end
                    saveas(gcf, app.imgpath)
                end
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResultWindowFigure = uifigure('Visible', 'off');
            app.ResultWindowFigure.Name = 'MATLAB App';
            app.ReturnButton = uibutton(app.ResultWindowFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnButtonPushed, true);
            app.ReturnButton.Text = '返回';
            app.TextArea = uitextarea(app.ResultWindowFigure);
            app.VisualizeButton = uibutton(app.ResultWindowFigure, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButtonPushed, true);
            app.VisualizeButton.Text = '可视化';
            % Show the figure after all components are created
            app.ResultWindowFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultWindowFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultWindowFigure)
        end
    end
end