classdef AOATOF_Data_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AOATOFvalueinputUIFigure  matlab.ui.Figure
        AOATOF_RandomTimes        matlab.ui.control.NumericEditField
        EditField_2Label_2        matlab.ui.control.Label
        AOATOF_Target_number      matlab.ui.control.NumericEditField
        EditFieldLabel_2          matlab.ui.control.Label
        AOATOF_Hight              matlab.ui.control.NumericEditField
        Label_4                   matlab.ui.control.Label
        AOATOF_ReturnButton       matlab.ui.control.Button
        AOATOF_AP_UITable         matlab.ui.control.Table
        APLabel                   matlab.ui.control.Label
        AOATOF_Delect_AP_Button   matlab.ui.control.Button
        AOATOF_Append_AP_Button   matlab.ui.control.StateButton
        mLabel_4                  matlab.ui.control.Label
        AOATOF_ComfirmButton      matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        AOATOF_Width              matlab.ui.control.NumericEditField
        EditField_2Label          matlab.ui.control.Label
        AOATOF_Long               matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
         ResultApp  % CallingApp
         AddApp
         Addnumber  % Addnumber=0代表添加的是AP点坐标，为1表示添加的是定位目标坐标

         AOATOF_AP_Excel
         AOATOF_AP_Path
         AOATOF_AP_clounm_num

         mouse_ind_clounm

         L, W, H, TarNum, RandomTimes, APCoordinate
    end

    properties (Access = public)
        Appnumber = 1 % Description
    end
    
    methods (Access = private)
        
        function AOATOF_AP_Display(app)
        % The code for the operation of AOATOF_AP data
            % Read the data of the EXCEL files
            app.AOATOF_AP_Excel = readtable(app.AOATOF_AP_Path);
            % Fetching the clounm number and padding the clounm name
            app.AOATOF_AP_clounm_num = size(app.AOATOF_AP_Excel,1);
            app.AOATOF_AP_UITable.RowName = "AP_"+(0:app.AOATOF_AP_clounm_num);
            % Display the data on the table
            app.AOATOF_AP_UITable.Data = app.AOATOF_AP_Excel;
        end 

        function assignment(app)
            app.L = app.AOATOF_Long.Value;
            app.W = app.AOATOF_Width.Value;
            app.H = app.AOATOF_Hight.Value;
            app.TarNum = app.AOATOF_Target_number.Value;
            app.RandomTimes = app.AOATOF_RandomTimes.Value;
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % The path of data of coordinate of AP and Target 
            app.AOATOF_AP_Path = "AOATOF_AP_Data.xlsx";
            AOATOF_AP_Display(app)
 
        end

        % Button pushed function: AOATOF_ReturnButton
        function AOATOF_ReturnButtonPushed(app, event)
            set(app.AOATOFvalueinputUIFigure,'Visible','off'); 
            run MainWindow.mlapp
        end

        % Button pushed function: AOATOF_ComfirmButton
        function AOATOF_ComfirmButtonPushed(app, event)
            assignment(app)
            AOATOF_AP_Display(app)
            app.APCoordinate = readmatrix("AOATOF_AP_Data.xlsx");
            
            set(app.AOATOFvalueinputUIFigure,'Visible','off'); 
            app.ResultApp = ResultWindow(app, app.Appnumber, app.L, app.W, app.H, app.TarNum, app.RandomTimes, app.APCoordinate, app.AOATOF_AP_clounm_num);
        end

        % Value changed function: AOATOF_Append_AP_Button
        function AOATOF_Append_AP_ButtonValueChanged(app, event)
            app.Addnumber = 0;
            app.AddApp = Dec_Add_Coordination(app, app.Appnumber, app.Addnumber);    
        end

        % Button pushed function: AOATOF_Delect_AP_Button
        function AOATOF_Delect_AP_ButtonPushed(app, event)
            % Stuff the blank on the row
            app.AOATOF_AP_UITable.Data(app.mouse_ind_clounm,:) = [];
            delete(app.AOATOF_AP_Path);
            % Write data to excels          
            writetable(app.AOATOF_AP_UITable.Data,app.AOATOF_AP_Path); 
            AOATOF_AP_Display(app)
        end

        % Cell selection callback: AOATOF_AP_UITable
        function AOATOF_AP_UITableCellSelection(app, event)
            indices = event.Indices;
            % The mouse selects the row
            app.mouse_ind_clounm = indices(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AOATOFvalueinputUIFigure = uifigure('Visible', 'off');
            app.AOATOFvalueinputUIFigure.Name = 'AOATOF value input';
            app.EditFieldLabel = uilabel(app.AOATOFvalueinputUIFigure);
            app.EditFieldLabel.Text = '长';
            app.AOATOF_Long = uieditfield(app.AOATOFvalueinputUIFigure, 'numeric');
            app.EditField_2Label = uilabel(app.AOATOFvalueinputUIFigure);
            app.EditField_2Label.Text = '宽';
            app.AOATOF_Width = uieditfield(app.AOATOFvalueinputUIFigure, 'numeric');
            app.Button = uibutton(app.AOATOFvalueinputUIFigure, 'push');
            app.Button.Text = '';
            app.Label = uilabel(app.AOATOFvalueinputUIFigure);
            app.Label.Text = '建立模拟室内';
            app.AOATOF_ComfirmButton = uibutton(app.AOATOFvalueinputUIFigure, 'push');
            app.AOATOF_ComfirmButton.ButtonPushedFcn = createCallbackFcn(app, @AOATOF_ComfirmButtonPushed, true);
            app.AOATOF_ComfirmButton.Text = '确定';
            app.mLabel_4 = uilabel(app.AOATOFvalueinputUIFigure);
            app.mLabel_4.Text = '建立室内大小（m）：';
            app.AOATOF_Append_AP_Button = uibutton(app.AOATOFvalueinputUIFigure, 'state');
            app.AOATOF_Append_AP_Button.ValueChangedFcn = createCallbackFcn(app, @AOATOF_Append_AP_ButtonValueChanged, true);
            app.AOATOF_Append_AP_Button.Text = '添加AP点';
            app.AOATOF_Delect_AP_Button = uibutton(app.AOATOFvalueinputUIFigure, 'push');
            app.AOATOF_Delect_AP_Button.ButtonPushedFcn = createCallbackFcn(app, @AOATOF_Delect_AP_ButtonPushed, true);
            app.AOATOF_Delect_AP_Button.Text = '删除AP点';
            app.APLabel = uilabel(app.AOATOFvalueinputUIFigure);
            app.APLabel.Text = '增删AP点';
            app.AOATOF_AP_UITable = uitable(app.AOATOFvalueinputUIFigure);
            app.AOATOF_AP_UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.AOATOF_AP_UITable.RowName = {};
            app.AOATOF_AP_UITable.CellSelectionCallback = createCallbackFcn(app, @AOATOF_AP_UITableCellSelection, true);
            app.AOATOF_ReturnButton = uibutton(app.AOATOFvalueinputUIFigure, 'push');
            app.AOATOF_ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @AOATOF_ReturnButtonPushed, true);
            app.AOATOF_ReturnButton.Text = '返回';
            app.Label_4 = uilabel(app.AOATOFvalueinputUIFigure);
            app.Label_4.Text = '高';
            app.AOATOF_Hight = uieditfield(app.AOATOFvalueinputUIFigure, 'numeric');
            app.EditFieldLabel_2 = uilabel(app.AOATOFvalueinputUIFigure);
            app.EditFieldLabel_2.Text = '定位点个数';
            app.AOATOF_Target_number = uieditfield(app.AOATOFvalueinputUIFigure, 'numeric');
            app.EditField_2Label_2 = uilabel(app.AOATOFvalueinputUIFigure);
            app.EditField_2Label_2.Text = '随机次数';
            app.AOATOF_RandomTimes = uieditfield(app.AOATOFvalueinputUIFigure, 'numeric');
            % Show the figure after all components are created
            app.AOATOFvalueinputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOATOF_Data_Input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AOATOFvalueinputUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AOATOFvalueinputUIFigure)
        end
    end
end