classdef AOA_Data_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AOAvalueinputUIFigure  matlab.ui.Figure
        AOA_RandomTimes        matlab.ui.control.NumericEditField
        EditField_2Label_2     matlab.ui.control.Label
        AOA_Target_number      matlab.ui.control.NumericEditField
        EditFieldLabel_2       matlab.ui.control.Label
        AOA_Width              matlab.ui.control.NumericEditField
        EditField_3Label       matlab.ui.control.Label
        AOA_Long               matlab.ui.control.NumericEditField
        EditField_2Label       matlab.ui.control.Label
        mLabel                 matlab.ui.control.Label
        AOA_Hight              matlab.ui.control.NumericEditField
        EditFieldLabel         matlab.ui.control.Label
        AOA_ReturnButton       matlab.ui.control.Button
        AOA_AP_UITable         matlab.ui.control.Table
        APLabel                matlab.ui.control.Label
        AOA_Delect_AP_Button   matlab.ui.control.Button
        AOA_Append_AP_Button   matlab.ui.control.StateButton
        AOA_ComfirmButton      matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        Button                 matlab.ui.control.Button
    end

    
    properties (Access = private)
         ResultApp  % CallingApp
         AddApp
         Addnumber  % Addnumber=0代表添加的是AP点坐标，为1表示添加的是定位目标坐标

         AOA_AP_Excel
         AOA_AP_Path
         AOA_AP_clounm_num

         mouse_ind_clounm

         L, W, H, TarNum, RandomTimes, APCoordinate
    end
    
    properties (Access = public)
        Appnumber = 0 % Description
    end
   
    methods (Access = private)
        function AOA_AP_Display(app)
            % The code for the operation of AOA_AP data
            % Read the data of the EXCEL files
            app.AOA_AP_Excel = readtable(app.AOA_AP_Path);
            % Fetching the clounm number and padding the clounm name
            app.AOA_AP_clounm_num = size(app.AOA_AP_Excel,1);
            app.AOA_AP_UITable.RowName = "AP_"+(0:app.AOA_AP_clounm_num);
            % Display the data on the table
            app.AOA_AP_UITable.Data = app.AOA_AP_Excel;
        end 

        function assignment(app)
            app.L = app.AOA_Long.Value;
            app.W = app.AOA_Width.Value;
            app.H = app.AOA_Hight.Value;
            app.TarNum = app.AOA_Target_number.Value;
            app.RandomTimes = app.AOA_RandomTimes.Value;  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % The path of data of coordinate of AP and Target 
            app.AOA_AP_Path = "AOA_AP_Data.xlsx";
            AOA_AP_Display(app)
        end

        % Button pushed function: AOA_ReturnButton
        function AOA_ReturnButtonPushed(app, event)
            set(app.AOAvalueinputUIFigure,'Visible','off'); 
            run MainWindow.mlapp
        end

        % Button pushed function: AOA_ComfirmButton
        function AOA_ComfirmButtonPushed(app, event)
            assignment(app)
            AOA_AP_Display(app)
            app.APCoordinate = readmatrix("AOA_AP_Data.xlsx");
            
            set(app.AOAvalueinputUIFigure,'Visible','off');
            app.ResultApp = ResultWindow(app, app.Appnumber, app.L, app.W, app.H, app.TarNum, app.RandomTimes, app.APCoordinate, app.AOA_AP_clounm_num);
        end

        % Value changed function: AOA_Append_AP_Button
        function AOA_Append_AP_ButtonValueChanged(app, event)
            app.Addnumber = 0;
            app.AddApp = Dec_Add_Coordination(app, app.Appnumber, app.Addnumber);        
        end

        % Button pushed function: AOA_Delect_AP_Button
        function AOA_Delect_AP_ButtonPushed(app, event)
            % Stuff the blank on the row
            app.AOA_AP_UITable.Data(app.mouse_ind_clounm,:) = [];
            delete(app.AOA_AP_Path);
            % Write data to excels          
            writetable(app.AOA_AP_UITable.Data,app.AOA_AP_Path); 
            AOA_AP_Display(app)
        end

        % Cell selection callback: AOA_AP_UITable
        function AOA_AP_UITableCellSelection(app, event)
            indices = event.Indices;
            % The mouse selects the row
            app.mouse_ind_clounm = indices(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AOAvalueinputUIFigure = uifigure('Visible', 'off');
            app.AOAvalueinputUIFigure.Name = 'AOA value input';
            app.Button = uibutton(app.AOAvalueinputUIFigure, 'push');
            app.Button.Text = '';
            app.Label = uilabel(app.AOAvalueinputUIFigure);
            app.Label.Text = '建立模拟室内';
            app.AOA_ComfirmButton = uibutton(app.AOAvalueinputUIFigure, 'push');
            app.AOA_ComfirmButton.ButtonPushedFcn = createCallbackFcn(app, @AOA_ComfirmButtonPushed, true);
            app.AOA_ComfirmButton.Text = '确定';
            app.AOA_Append_AP_Button = uibutton(app.AOAvalueinputUIFigure, 'state');
            app.AOA_Append_AP_Button.ValueChangedFcn = createCallbackFcn(app, @AOA_Append_AP_ButtonValueChanged, true);
            app.AOA_Append_AP_Button.Text = '添加AP点';
            app.AOA_Delect_AP_Button = uibutton(app.AOAvalueinputUIFigure, 'push');
            app.AOA_Delect_AP_Button.ButtonPushedFcn = createCallbackFcn(app, @AOA_Delect_AP_ButtonPushed, true);
            app.AOA_Delect_AP_Button.Text = '删除AP点';
            app.APLabel = uilabel(app.AOAvalueinputUIFigure);
            app.APLabel.Text = '增删AP点';
            app.AOA_AP_UITable = uitable(app.AOAvalueinputUIFigure);
            app.AOA_AP_UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.AOA_AP_UITable.RowName = {};
            app.AOA_AP_UITable.SelectionType = 'row';
            app.AOA_AP_UITable.CellSelectionCallback = createCallbackFcn(app, @AOA_AP_UITableCellSelection, true);
            app.AOA_ReturnButton = uibutton(app.AOAvalueinputUIFigure, 'push');
            app.AOA_ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @AOA_ReturnButtonPushed, true);
            app.AOA_ReturnButton.Text = '返回';
            app.EditFieldLabel = uilabel(app.AOAvalueinputUIFigure);
            app.EditFieldLabel.Text = '高';
            app.AOA_Hight = uieditfield(app.AOAvalueinputUIFigure, 'numeric');
            app.mLabel = uilabel(app.AOAvalueinputUIFigure);
            app.mLabel.Text = '建立室内大小（m）：';
            app.EditField_2Label = uilabel(app.AOAvalueinputUIFigure);
            app.EditField_2Label.Text = '长';
            app.AOA_Long = uieditfield(app.AOAvalueinputUIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.AOAvalueinputUIFigure);
            app.EditField_3Label.Text = '宽';
            app.AOA_Width = uieditfield(app.AOAvalueinputUIFigure, 'numeric');
            app.EditFieldLabel_2 = uilabel(app.AOAvalueinputUIFigure);
            app.EditFieldLabel_2.Text = '定位点个数';
            app.AOA_Target_number = uieditfield(app.AOAvalueinputUIFigure, 'numeric');
            app.EditField_2Label_2 = uilabel(app.AOAvalueinputUIFigure);
            app.EditField_2Label_2.Text = '随机次数';
            app.AOA_RandomTimes = uieditfield(app.AOAvalueinputUIFigure, 'numeric');
            % Show the figure after all components are created
            app.AOAvalueinputUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AOA_Data_Input

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AOAvalueinputUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AOAvalueinputUIFigure)
        end
    end
end