classdef Dec_Add_Coordination < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DecAddUIFigure   matlab.ui.Figure
        mLabel_3         matlab.ui.control.Label
        mLabel_2         matlab.ui.control.Label
        mLabel           matlab.ui.control.Label
        ComfirmButton    matlab.ui.control.Button
        ZEditField       matlab.ui.control.NumericEditField
        ZEditFieldLabel  matlab.ui.control.Label
        YEditField       matlab.ui.control.NumericEditField
        YEditFieldLabel  matlab.ui.control.Label
        XEditField       matlab.ui.control.NumericEditField
        XEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description

        The_Appnumber
        The_Addnumber

        AOA_AP_Excel
        AOA_AP_Path
        AOA_AP_clounm_num
        AOATOF_AP_Excel
        AOATOF_AP_Path
        AOATOF_AP_clounm_num

        newdata
    end
    
    methods (Access = private)
        
        function AOA_AP_Display(app)
    % The code for the operation of AOA_AP data
            if app.The_Appnumber == 0 && app.The_Addnumber == 0  
                % Add new data to AP_Excel
                app.AOA_AP_Excel = [app.AOA_AP_Excel; app.newdata];
                writetable(app.AOA_AP_Excel,app.AOA_AP_Path);
                % Read the data of the EXCEL files
                app.AOA_AP_Excel = readtable(app.AOA_AP_Path);
                % Fetching the clounm number and padding the clounm name
                app.AOA_AP_clounm_num = size(app.AOA_AP_Excel,1);
                app.CallingApp.AOA_AP_UITable.RowName = "AP_"+(0:app.AOA_AP_clounm_num);
                % Display the data on the table
                app.CallingApp.AOA_AP_UITable.Data = app.AOA_AP_Excel;
            elseif app.The_Appnumber == 1 && app.The_Addnumber == 0
                app.AOATOF_AP_Excel = [app.AOATOF_AP_Excel; app.newdata];
                writetable(app.AOATOF_AP_Excel,app.AOATOF_AP_Path);
                app.AOATOF_AP_Excel = readtable(app.AOATOF_AP_Path);
                app.AOATOF_AP_clounm_num = size(app.AOATOF_AP_Excel,1);
                app.CallingApp.AOATOF_AP_UITable.RowName = "AP_"+(0:app.AOATOF_AP_clounm_num);
                app.CallingApp.AOATOF_AP_UITable.Data = app.AOATOF_AP_Excel;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, UpperApp, Appnumber, Addnumber)
            app.CallingApp = UpperApp;
            app.The_Addnumber = Addnumber;
            app.The_Appnumber = Appnumber;

            app.AOA_AP_Path = "AOA_AP_Data.xlsx";
            app.AOATOF_AP_Path = "AOATOF_AP_Data.xlsx";
            app.AOA_AP_Excel = readtable(app.AOA_AP_Path);
            app.AOATOF_AP_Excel = readtable(app.AOATOF_AP_Path);
        end

        % Button pushed function: ComfirmButton
        function ComfirmButtonPushed(app, event)
            app.newdata = {app.XEditField.Value, app.YEditField.Value, app.ZEditField.Value};
            %Display data
            AOA_AP_Display(app)
            delete(app)
        end

        % Close request function: DecAddUIFigure
        function DecAddUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DecAddUIFigure = uifigure('Visible', 'off');
            app.DecAddUIFigure.Name = '请输入坐标';
            app.DecAddUIFigure.CloseRequestFcn = createCallbackFcn(app, @DecAddUIFigureCloseRequest, true);
            app.XEditFieldLabel = uilabel(app.DecAddUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.DecAddUIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.DecAddUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.DecAddUIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DecAddUIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.DecAddUIFigure, 'numeric');
            app.ComfirmButton = uibutton(app.DecAddUIFigure, 'push');
            app.ComfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ComfirmButtonPushed, true);
            app.ComfirmButton.Text = '确定';
            app.mLabel = uilabel(app.DecAddUIFigure);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.DecAddUIFigure);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.DecAddUIFigure);
            app.mLabel_3.Text = 'm';
            % Show the figure after all components are created
            app.DecAddUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dec_Add_Coordination(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DecAddUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DecAddUIFigure)
        end
    end
end